classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SeleccinTab                     matlab.ui.container.Tab
        CargararchivoButton             matlab.ui.control.Button
        ReproduciraudioButton           matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        TipodefiltroDropDown            matlab.ui.control.DropDown
        TipodefiltroDropDownLabel       matlab.ui.control.Label
        FrecuenciaCentralEditFieldLabel  matlab.ui.control.Label
        FrecuenciaCentralEditField      matlab.ui.control.NumericEditField
        FactordecalidadEditFieldLabel   matlab.ui.control.Label
        FactordecalidadEditField        matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortebajaEditField  matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortealtaEditField  matlab.ui.control.NumericEditField
        ReproduciraudiofiltradoButton   matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        GainSlider                      matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ExportarButton                  matlab.ui.control.Button
        SalvaraudiofiltradoButton       matlab.ui.control.Button
        NombredearchivoEditField        matlab.ui.control.EditField
        NombredearchivoEditFieldLabel   matlab.ui.control.Label
        SeleccindefiltroLabel           matlab.ui.control.Label
        ParmetrosdelfiltroLabel         matlab.ui.control.Label
        PausarButton                    matlab.ui.control.Button
        ResumirButton                   matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        TemporalButton                  matlab.ui.control.Button
        EfectosTab                      matlab.ui.container.Tab
        PhaseroFlangerCheckBox          matlab.ui.control.CheckBox
        EcoCheckBox                     matlab.ui.control.CheckBox
        ReverberacinCheckBox            matlab.ui.control.CheckBox
        DistorsinCheckBox               matlab.ui.control.CheckBox
        VibratoCheckBox                 matlab.ui.control.CheckBox
        RATEHzKnobLabel                 matlab.ui.control.Label
        RATEHzKnob                      matlab.ui.control.Knob
        MODFREQKnobLabel                matlab.ui.control.Label
        MODFREQKnob                     matlab.ui.control.Knob
        WIDTHKnobLabel                  matlab.ui.control.Label
        WIDTHKnob                       matlab.ui.control.Knob
        RATEKnob_2Label                 matlab.ui.control.Label
        RATEKnob_2                      matlab.ui.control.Knob
        DELAYKnobLabel                  matlab.ui.control.Label
        DELAYKnob                       matlab.ui.control.Knob
        DELAYmsKnobLabel                matlab.ui.control.Label
        DELAYmsKnob                     matlab.ui.control.Knob
        RATEHzKnob_2Label               matlab.ui.control.Label
        RATEHzKnob_2                    matlab.ui.control.Knob
        GAINKnobLabel                   matlab.ui.control.Label
        GAINKnob                        matlab.ui.control.Knob
        MIXKnobLabel                    matlab.ui.control.Label
        MIXKnob                         matlab.ui.control.Knob
        DELAYmsKnob_2Label              matlab.ui.control.Label
        DELAYmsKnob_2                   matlab.ui.control.Knob
        ATENUACINKnobLabel              matlab.ui.control.Label
        ATENUACINKnob                   matlab.ui.control.Knob
        GraficosTab                     matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties(Access=public)
        file2
        player
        player2
        player3
        player4
        salfir_x
        saliir_x
    end
    methods (Access = private)


        function results = func(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargararchivoButton
        function CargarArchivo(app, event)

            clearvars file;
            clearvars x;
            clearvars Fs;
            
            global file;
            global x; 
            global Fs; 
            global len;
      
            file = uigetfile({'*.wav;*.mp3', 'Audio Files'});   % selecciona un archivo con extensiones específicas
            
            if  file == 0
                app.Label.Text = 'Archivo no seleccionado';
                app.Lamp_2.Color = 'r';
            else
                app.Label.Text = file;
                [x, Fs] = audioread(file);  % obtiene los datos de un archivo *.wav
                len = length(x);
                app.player = audioplayer(x,Fs);
                app.Lamp_2.Color = 'g';
            end
            
        end

        % Value changed function: TipodefiltroDropDown
        function TipodefiltroDropDownValueChanged(app, event)
            value = app.TipodefiltroDropDown.Value;
            %datos generales
            syms s z
            global x;
            global Hd;
            global fa;
            global fb;
            global Fs;
            global Ts;
            global len;
            global gain;
            fa=app.FrecuenciadecortealtaEditField.Value;
            fb=app.FrecuenciadecortebajaEditField.Value;
            gain= app.GainSlider.Value;
            Ts=1/Fs;
            %datos para filtro IIR
            %prototipo analogico
            H(s) = 1 / (s^2+sqrt(2)*s+1);
            %datos para filtro FIR
            if value =='FIR'
                %progra filtro FIR         
                Fpass1fir=fb;%frecuencia de corte baja
                Fpass2fir=fa; %frecuencia de corte alta
                Fstop1fir=abs(fb-500); %primer frecuencia de paro
                Fstop2fir=abs(fa+500);% segunda frecuencia de paro
                Dstop1= 0.001;% primer stopband atenuation
                Dpass=0.057501127785;%passband ripple
                Dstop2=0.0001;%segundo stopband atenuation
                flag = 'scale';
                %calculo de parametros de orden usando kaiserord
                [N,Wn,BETA,TYPE] = kaiserord([Fstop1fir Fpass1fir Fpass2fir Fstop2fir]/(Fs/2), [0 1 0], [Dstop1 Dpass Dstop2]);
                %ver progra defensiva ya que si es 0 ambas frecuencias no
                %funciona, la frecuencia de corte a debe de ser menor a la
                %b porque si no no funciona.
                
                bfir= fir1(N,Wn,TYPE,kaiser(N+1,BETA), flag);
                Hd= dfilt.dffir(bfir);
                app.salfir_x = zeros(1,len);
                app.salfir_x= filter(bfir,1,x);  
            elseif value == 'IIR' 
                %Progra de filtro IIR
            else
                
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            global x;
            global Hd;
            t = linspace(0,1000000000);
            plot(app.UIAxes2,x);
            plot(app.UIAxes3,app.salfir_x);
            
            
        end

        % Button pushed function: ExportarButton
        function Exportar(app, event)
            
            global x_out;                               % aquí va la variable del audio modificado
            global Fs;
            
            out_filename = app.NombredearchivoEditField.Value; % guarda nombre ingresado en la campo de edición
            out_filename = strcat(out_filename, ".wav")
            [file, path] = uiputfile(out_filename); % obtiene la dirección y el nombre para guardar el archivo
            
            % en caso de que se cancele "guardar archivo"
            if file == 0   
                return;
            end
            
            fullFileName = fullfile(path, file);    % construye la ubicación del archivo
            audiowrite(fullFileName, x_out, Fs)         % guarda el archivo en un formato *.wav
            
        end

        % Value changed function: EcoCheckBox
        function toggle_eco_effect(app, event)
            
            value = app.EcoCheckBox.Value;
            
            global x;
            global x_out;
            global Fs;
            
            delay1 = app.DELAYmsKnob_2.Value;
            delay2 = delay1 + 0.1;
            
            a1 = app.ATENUACINKnob.Value;
            a2 = a1 - 0.1;
            
            R1 = round(delay1*Fs);     % retardo 1
            R2 = round(delay2*Fs);     % retardo 2
            
            if value
                for n = R2+1:1:length(x)
                    x_out(n) = x(n) + a1*x(n-R1) + a2*x_out(n-R2);
                end
            else
                x_out = x;
            end
            
        end

        % Value changed function: ReverberacinCheckBox
        function toggle_reverberation_effect(app, event)
            
            value = app.ReverberacinCheckBox.Value;
            
            global x;
            global x_out;
            
            Delay = app.DELAYmsKnob.Value;
            Rate = app.RATEHzKnob.Value;
            
            R1 = round(Delay*Rate);  % retardo 1
            R2 = round(2*R1);      % retardo 2
            R3 = round(0.5*R1);    % retardo 3
            
            a1 = 0.2;   a2 = 0.2;   % atenuaciones
            
            if value
                for n = R2+R3+1:1:length(x)
                    x_out(n) = x(n) + x(n-R2) + a2*x(n-R2-R3) + a1*x_out(n-R1);
                end
            else
                x_out = x;
            end
        end

        % Value changed function: VibratoCheckBox
        function toggle_vibrato_effect(app, event)
            global x;
            global x_out;

            value = app.VibratoCheckBox.Value;
            
            if value
                Rate = app.RATEHzKnob.Value
                Width = app.WIDTHKnob.Value
                Modfreq = app.MODFREQKnob.Value
                
                Delay = Width; % delay básico en segundos (0 - 0.01 segundos)
                DelayM = round(Delay*Rate); % delay básico en # de muestras
                AnchoM = round(Width*Rate); % ancho de modulación en # de muestras
                
                if AnchoM > DelayM 
                  error('delay greater than basic delay !!!');
                  return;
                end 
                
                ModfreqM = Modfreq/Rate; % frecuencia de modulación en # de muestras
                N = length(x);        % # of samples in WAV-file
                Delay_length = 2 + DelayM + AnchoM * 2;    % length of the entire delay  
                Delayline = zeros(Delay_length,1); % memory allocation for delay
                x_out = zeros(size(x));     % memory allocation for output vector
                for n=1:(N-1)
                   M = ModfreqM;
                   MOD = sin(M*2*pi*n);
                   TAP = 1 + DelayM + AnchoM*MOD;
                   i = floor(TAP);
                   frac = TAP - i;
                   Delayline=[x(n); Delayline(1:Delay_length-1)]; 
                   x_out(n) = Delayline(i+1)*frac + Delayline(i)*(1-frac);
                end  
                
            else
                x_out = x;
            end
        end

        % Value changed function: PhaseroFlangerCheckBox
        function toggle_phaser_effect(app, event)
            
            global Fs;
            global x;
            global x_out;
            
            value = app.PhaseroFlangerCheckBox.Value;
            if value
                max_delay = app.DELAYmsKnob.Value;
                rate = app.RATEHzKnob_2.Value;
                
                osc_sin_ref = (sin(2*pi*(1:length(x))*(rate/Fs)));
                max_samp_delay = round(max_delay*Fs);
                x_out(1:max_samp_delay)=x(1:max_samp_delay);

                amp = 0.7;
                
                for n = (max_samp_delay+1):length(x)
                    sin_val = abs(osc_sin_ref(n));
                    delay = ceil(sin_val*max_samp_delay);
                    x_out(n) = (amp*x(n)) + amp*(x(n-delay));
                end
            else
                x_out = x;
            end
            
        end

        % Value changed function: DistorsinCheckBox
        function toggle_distortion_effect(app, event)
            
            global x;
            global x_out;
            
            value = app.DistorsinCheckBox.Value;
            
            if value
                gain = app.GAINKnob.Value;
                mix = app.MIXKnob.Value;
                
                q=x*gain/max(abs(x));
                z=sign(-q).*(1-exp(sign(-q).*q));
                x_out=mix*z*max(abs(x))/max(abs(z))+(1-mix)*x;
                x_out=x_out.*max(abs(x))/max(abs(x_out));
            else
                x_out = x;
            end
            %Actualización: Implementación de efecto de Distorsión
            %En esta actualización se incluye la implementación de efecto de Distorsión. Para esto se utilizaron dos knob control para modificar la ganancia y el mix de la distorsión.
        end

        % Button pushed function: PausarButton
        function PausarButtonPushed(app, event)
            pause(app.player);%pausa la reproduccion de audio
            app.Lamp_2.Color = 'r';
        end

        % Button pushed function: ResumirButton
        function ResumirButtonPushed(app, event)
            resume(app.player);%resume la reproduccion del audio donde se quedo
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop(app.player);%da fin a la reproduccion del audio
        end

        % Button pushed function: ReproduciraudioButton
        function ReproduciraudioButtonPushed(app, event)
            play(app.player);%reproduce el audio
        end

        % Button pushed function: TemporalButton
        function play_audio_effect(app, event)
            global Fs;
            global x_out;

            soundsc(x_out, Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SeleccinTab = uitab(app.TabGroup);
            app.SeleccinTab.Title = 'Selección';
            app.CargararchivoButton = uibutton(app.SeleccinTab, 'push');
            app.CargararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivo, true);
            app.CargararchivoButton.Text = 'Cargar archivo';
            app.ReproduciraudioButton = uibutton(app.SeleccinTab, 'push');
            app.ReproduciraudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciraudioButtonPushed, true);
            app.ReproduciraudioButton.Text = 'Reproducir audio ';
            app.Lamp_2 = uilamp(app.SeleccinTab);
            app.Label = uilabel(app.SeleccinTab);
            app.Label.Text = '';
            app.TipodefiltroDropDown = uidropdown(app.SeleccinTab);
            app.TipodefiltroDropDown.Items = {'FIR', 'IIR'};
            app.TipodefiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodefiltroDropDownValueChanged, true);
            app.TipodefiltroDropDown.Value = 'FIR';
            app.TipodefiltroDropDownLabel = uilabel(app.SeleccinTab);
            app.TipodefiltroDropDownLabel.Text = 'Tipo de filtro';
            app.FrecuenciaCentralEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciaCentralEditFieldLabel.Text = 'Frecuencia Central';
            app.FrecuenciaCentralEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FactordecalidadEditFieldLabel = uilabel(app.SeleccinTab);
            app.FactordecalidadEditFieldLabel.Text = 'Factor de calidad';
            app.FactordecalidadEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FrecuenciadecortebajaEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciadecortebajaEditFieldLabel.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FrecuenciadecortealtaEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciadecortealtaEditFieldLabel.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.ReproduciraudiofiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.ReproduciraudiofiltradoButton.Text = 'Reproducir audio filtrado';
            app.PlotButton = uibutton(app.SeleccinTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GainSlider = uislider(app.SeleccinTab);
            app.GainSlider.Limits = [0 1];
            app.GainSliderLabel = uilabel(app.SeleccinTab);
            app.GainSliderLabel.Text = 'Gain';
            app.Lamp = uilamp(app.SeleccinTab);
            app.ExportarButton = uibutton(app.SeleccinTab, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @Exportar, true);
            app.ExportarButton.Text = 'Exportar';
            app.SalvaraudiofiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.SalvaraudiofiltradoButton.Text = 'Salvar audio filtrado';
            app.NombredearchivoEditField = uieditfield(app.SeleccinTab, 'text');
            app.NombredearchivoEditFieldLabel = uilabel(app.SeleccinTab);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.SeleccindefiltroLabel = uilabel(app.SeleccinTab);
            app.SeleccindefiltroLabel.Text = 'Selección de filtro';
            app.ParmetrosdelfiltroLabel = uilabel(app.SeleccinTab);
            app.ParmetrosdelfiltroLabel.Text = 'Parámetros del filtro';
            app.PausarButton = uibutton(app.SeleccinTab, 'push');
            app.PausarButton.ButtonPushedFcn = createCallbackFcn(app, @PausarButtonPushed, true);
            app.PausarButton.Text = 'Pausar';
            app.ResumirButton = uibutton(app.SeleccinTab, 'push');
            app.ResumirButton.ButtonPushedFcn = createCallbackFcn(app, @ResumirButtonPushed, true);
            app.ResumirButton.Text = 'Resumir';
            app.STOPButton = uibutton(app.SeleccinTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TemporalButton = uibutton(app.SeleccinTab, 'push');
            app.TemporalButton.ButtonPushedFcn = createCallbackFcn(app, @play_audio_effect, true);
            app.TemporalButton.Text = 'Temporal Button ';
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.PhaseroFlangerCheckBox = uicheckbox(app.EfectosTab);
            app.PhaseroFlangerCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_phaser_effect, true);
            app.PhaseroFlangerCheckBox.Text = 'Phaser o Flanger';
            app.EcoCheckBox = uicheckbox(app.EfectosTab);
            app.EcoCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_eco_effect, true);
            app.EcoCheckBox.Text = 'Eco';
            app.ReverberacinCheckBox = uicheckbox(app.EfectosTab);
            app.ReverberacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_reverberation_effect, true);
            app.ReverberacinCheckBox.Text = 'Reverberación';
            app.DistorsinCheckBox = uicheckbox(app.EfectosTab);
            app.DistorsinCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_distortion_effect, true);
            app.DistorsinCheckBox.Text = 'Distorsión';
            app.VibratoCheckBox = uicheckbox(app.EfectosTab);
            app.VibratoCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_vibrato_effect, true);
            app.VibratoCheckBox.Text = 'Vibrato';
            app.RATEHzKnobLabel = uilabel(app.EfectosTab);
            app.RATEHzKnobLabel.Text = 'RATE (Hz)';
            app.RATEHzKnob = uiknob(app.EfectosTab, 'continuous');
            app.RATEHzKnob.Limits = [0 30000];
            app.RATEHzKnob.Value = 1;
            app.MODFREQKnobLabel = uilabel(app.EfectosTab);
            app.MODFREQKnobLabel.Text = 'MODFREQ';
            app.MODFREQKnob = uiknob(app.EfectosTab, 'continuous');
            app.MODFREQKnob.Limits = [0 10];
            app.MODFREQKnob.MajorTicks = [0.1 1 2 3 4 5 6 7 8 9 10];
            app.MODFREQKnob.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.WIDTHKnobLabel = uilabel(app.EfectosTab);
            app.WIDTHKnobLabel.Text = 'WIDTH';
            app.WIDTHKnob = uiknob(app.EfectosTab, 'continuous');
            app.WIDTHKnob.Limits = [0 0.01];
            app.WIDTHKnob.MajorTicks = [0.0001 0.002 0.004 0.006 0.008 0.01];
            app.WIDTHKnob.MajorTickLabels = {'0', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.RATEKnob_2Label = uilabel(app.EfectosTab);
            app.RATEKnob_2Label.Text = 'RATE';
            app.RATEKnob_2 = uiknob(app.EfectosTab, 'continuous');
            app.RATEKnob_2.Limits = [0 30000];
            app.DELAYKnobLabel = uilabel(app.EfectosTab);
            app.DELAYKnobLabel.Text = 'DELAY';
            app.DELAYKnob = uiknob(app.EfectosTab, 'continuous');
            app.DELAYKnob.Limits = [0 1];
            app.DELAYKnob.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DELAYmsKnobLabel = uilabel(app.EfectosTab);
            app.DELAYmsKnobLabel.Text = 'DELAY (ms)';
            app.DELAYmsKnob = uiknob(app.EfectosTab, 'continuous');
            app.DELAYmsKnob.Limits = [0 0.01];
            app.DELAYmsKnob.Value = 0.01;
            app.RATEHzKnob_2Label = uilabel(app.EfectosTab);
            app.RATEHzKnob_2Label.Text = 'RATE (Hz)';
            app.RATEHzKnob_2 = uiknob(app.EfectosTab, 'continuous');
            app.RATEHzKnob_2.Limits = [0.1 5];
            app.RATEHzKnob_2.Value = 0.1;
            app.GAINKnobLabel = uilabel(app.EfectosTab);
            app.GAINKnobLabel.Text = 'GAIN';
            app.GAINKnob = uiknob(app.EfectosTab, 'continuous');
            app.GAINKnob.Limits = [0 25];
            app.MIXKnobLabel = uilabel(app.EfectosTab);
            app.MIXKnobLabel.Text = 'MIX';
            app.MIXKnob = uiknob(app.EfectosTab, 'continuous');
            app.MIXKnob.Limits = [0 1];
            app.MIXKnob.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MIXKnob.Value = 0.1;
            app.DELAYmsKnob_2Label = uilabel(app.EfectosTab);
            app.DELAYmsKnob_2Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_2 = uiknob(app.EfectosTab, 'continuous');
            app.DELAYmsKnob_2.Limits = [0 1];
            app.ATENUACINKnobLabel = uilabel(app.EfectosTab);
            app.ATENUACINKnobLabel.Text = 'ATENUACIÓN';
            app.ATENUACINKnob = uiknob(app.EfectosTab, 'continuous');
            app.ATENUACINKnob.Limits = [0 1];
            app.ATENUACINKnob.Value = 0.01;
            app.GraficosTab = uitab(app.TabGroup);
            app.GraficosTab.Title = 'Graficos';
            app.UIAxes2 = uiaxes(app.GraficosTab);
            title(app.UIAxes2, 'Audio original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraficosTab);
            title(app.UIAxes3, 'Audio procesado')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.GraficosTab);
            title(app.UIAxes, 'Respuesta en frecuencia del filtro')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end